classdef GUIvDRAFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RESETFETCHButton_2        matlab.ui.control.Button
        RESETIRBButton            matlab.ui.control.Button
        RESUMEButton              matlab.ui.control.Button
        EMERGENCYSTOPButton       matlab.ui.control.Button
        ZaxisLabel                matlab.ui.control.Label
        YaxisLabel                matlab.ui.control.Label
        Button_11                 matlab.ui.control.Button
        Button_10                 matlab.ui.control.Button
        Button_9                  matlab.ui.control.Button
        Button_8                  matlab.ui.control.Button
        Button_7                  matlab.ui.control.Button
        XaxisLabel                matlab.ui.control.Label
        CartesianMovementLabel    matlab.ui.control.Label
        SelectRobotDropDown       matlab.ui.control.DropDown
        SelectRobotDropDownLabel  matlab.ui.control.Label
        Button                    matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        modelFetch % Where I will put the loaded puma
        modelUR5
        qz = zeros(1,6);
        qz7 = zeros(1,7);
        
        q0_IRB = zeros(1,7);
        q0_Fetch = zeros(1,7);
        
        qrUR5 = [pi/4	pi/2  -pi/2	0	0	0];
        qrFetch = [0	pi/2  -pi/2	0	0	0   0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
             % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
%             Fetch;
            app.modelFetch = Fetch;
            app.modelFetch.model.base = transl([0.5,0,0]);
            app.modelFetch.model.animate(app.qrFetch)
%             plot(app.modelFetch, app.q0_Fetch,'workspace',[-2 2 -2 2 -0.5 2])            
            axis([-2 2 -2 2 -0.5 2]);
            hold on;         
            
            app.modelUR5 = UR5;
            app.modelUR5.model.base = transl([-0.5,0,0]);
            app.modelUR5.model.animate(app.qz);
            drawnow();

%             Delete the green floor surface (need to count backwards
%             because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
%             
            % Turn off this load button and enable the move button
%             app.MakeLoadedPumaMoveButton.Enable = true;
%             app.MakeLoadedUR5MoveButton.Enable = true;
            
        end

        % Button pushed function: RESETFETCHButton_2
        function RESETFETCHButton_2Pushed(app, event)
            qmatrix = jtraj(app.q0_Fetch,app.qrFetch,50);          
            for robotStepIndex = 1:size(qmatrix,1)

                app.modelFetch.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
                
            end
        end

        % Button pushed function: RESETIRBButton
        function RESETIRBButtonPushed(app, event)
            %test with UR5
            qmatrix = jtraj(app.qz, app.qrUR5, 50);
            for robotStepIndex = 1:size(qmatrix,1)
                
                app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
            end
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            app.modelUR5.model.teach()
            app.modelFetch.model.teach()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '+0.1';
            app.SelectRobotDropDownLabel = uilabel(app.UIFigure);
            app.SelectRobotDropDownLabel.Text = 'Select Robot';
            app.SelectRobotDropDown = uidropdown(app.UIFigure);
            app.SelectRobotDropDown.Items = {'IRB-1660', 'Fetch'};
            app.SelectRobotDropDown.Value = 'IRB-1660';
            app.CartesianMovementLabel = uilabel(app.UIFigure);
            app.CartesianMovementLabel.Text = 'Cartesian Movement';
            app.XaxisLabel = uilabel(app.UIFigure);
            app.XaxisLabel.Text = 'X-axis';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '-0.1';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '+0.1';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Text = '-0.1';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.Text = '-0.1';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.Text = '+0.1';
            app.YaxisLabel = uilabel(app.UIFigure);
            app.YaxisLabel.Text = 'Y-axis';
            app.ZaxisLabel = uilabel(app.UIFigure);
            app.ZaxisLabel.Text = 'Z-axis';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.RESUMEButton = uibutton(app.UIFigure, 'push');
            app.RESUMEButton.Text = 'RESUME';
            app.RESETIRBButton = uibutton(app.UIFigure, 'push');
            app.RESETIRBButton.ButtonPushedFcn = createCallbackFcn(app, @RESETIRBButtonPushed, true);
            app.RESETIRBButton.Text = 'RESET IRB';
            app.RESETFETCHButton_2 = uibutton(app.UIFigure, 'push');
            app.RESETFETCHButton_2.ButtonPushedFcn = createCallbackFcn(app, @RESETFETCHButton_2Pushed, true);
            app.RESETFETCHButton_2.Text = 'RESET FETCH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIvDRAFT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)init(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end